classdef scopeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        GetmeasurementButton  matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        GetfolderfileButton   matlab.ui.control.Button
        PlotPanel             matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Foldertree               matlab.ui.container.TreeNode
        Node                     matlab.ui.container.TreeNode
        ChannelSelection         matlab.ui.control.Label
        plotChoices                 %matlab.ui.control.CheckBox
        %         measuremetDoneLabel      matlab.ui.control.Label
        saveBox                  matlab.ui.control.CheckBox
        PlotButton               matlab.ui.control.Button
        FFTButton                matlab.ui.control.Button
        FilenameEditFieldLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        plotTitleEditFieldLabel  matlab.ui.control.Label
        plotTitleEditField       matlab.ui.control.EditField
        startPlotPacketLabel     matlab.ui.control.Label
        startPlotPacketNr        matlab.ui.control.NumericEditField
        endPlotPacketLabel       matlab.ui.control.Label
        endPlotPacketNr          matlab.ui.control.NumericEditField
        decodeDoneLabel          matlab.ui.control.Label
        DecodePanel              matlab.ui.container.Panel
        pnDecodePanel            matlab.ui.container.Panel
        pnDecodeBox              matlab.ui.control.CheckBox
        speDecodePanel           matlab.ui.container.Panel
        speDecodeBox             matlab.ui.control.CheckBox
        DecodeButton             matlab.ui.control.Button
        PNplotButton             matlab.ui.control.Button
        ethAnalyzeButton         matlab.ui.control.Button

        InformationPanel              matlab.ui.container.Panel
        Filename                      matlab.ui.control.Label
        SampleintervalEditField       matlab.ui.control.NumericEditField
        SampleintervalEditFieldLabel  matlab.ui.control.Label
        EndtimeEditField              matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel         matlab.ui.control.Label
        StarttimeEditField            matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel       matlab.ui.control.Label
    
        RecordLengthEditField    matlab.ui.control.NumericEditField
        RecordLengthEditFieldLabel  matlab.ui.control.Label
        pcapLamp                      matlab.ui.control.Lamp
        pcapLampLabel                 matlab.ui.control.Label
        scopeLamp                     matlab.ui.control.Lamp
        scopeLampLabel                matlab.ui.control.Label
        FilenameLabel                 matlab.ui.control.Label
        channelPlotRangeLabel         matlab.ui.control.Label
        mathPlotRangeLabel         matlab.ui.control.Label
        
        % plot panel
        PlotPanel_2           matlab.ui.container.Panel
        channelPanel           matlab.ui.container.Panel
        mathPanel             matlab.ui.container.Panel  
        EndtimeSpinner        matlab.ui.control.Spinner
        EndtimeLabel          matlab.ui.control.Label
        MathEndtimeSpinner        matlab.ui.control.Spinner
        MathEndtimeLabel          matlab.ui.control.Label
        StarttimeSpinner      matlab.ui.control.Spinner
        StarttimeLabel        matlab.ui.control.Label
        MathStarttimeSpinner      matlab.ui.control.Spinner
        MathStarttimeLabel matlab.ui.control.Label

        % FFT panel
        FFTPanel           matlab.ui.container.Panel
        FFTEndtimeSpinner        matlab.ui.control.Spinner
        FFTEndtimeLabel          matlab.ui.control.Label
        FFTStarttimeSpinner      matlab.ui.control.Spinner
        FFTStarttimeLabel        matlab.ui.control.Label

        WindowDropDown        matlab.ui.control.DropDown
        WindowDropDownLabel   matlab.ui.control.Label

        files
        folder
        selectedFile

        networkAnalyzerApp
        ethHeight = 2
        ethColor ='b'
        ax
        l
        screenSize 
        screenWidth 
        screenHeight 
    end
    properties(Access = public)
        chosenFile
        scopeObj
    end
    methods (Access = private)


        function lMoving(~, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % Add any other action here triggered by the movement of the sliders
        end

        function plotting(app,src,event)
            c=0;
            m=0;
            for i= 1 : numel(app.plotChoices)
                if app.plotChoices(i).checkbox.Value
                    if contains(lower(app.plotChoices(i).checkbox.Text),'ch')
                        c=c+1;
                        PlotCH(1,c) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'ch')));
                        plotChTitle(c,1) = string(app.plotChoices(i).TitleEditField.Value);
                    end
                    if contains(lower(app.plotChoices(i).checkbox.Text),'math')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'math')));
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end

                    if contains(lower(app.plotChoices(i).checkbox.Text),'mth')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'mth')));
                        if isnan(PlotMath(1,m))
                            PlotMath(1,m) = m;
                        end
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end
                end

            end
            if app.saveBox.Value
                savePlotEn = 'save';
                savePlotTitle = app.FilenameEditField.Value;
            else
                savePlotEn = [];
                savePlotTitle = [];
            end

            if c>0
                app.scopeObj.plotChannels('channels',PlotCH,savePlotEn,savePlotTitle,'titles',plotChTitle,'limit',[app.StarttimeSpinner.Value app.EndtimeSpinner.Value]);
            end
            if m>0
                app.scopeObj.plotMath('channels',PlotMath,savePlotEn,savePlotTitle,'titles',plotMathTitle);
            end
        end

        function plotFFT(app,src,event)
            %             set(app.UIFigure,'Pointer','watch'); drawnow;

            %             app.scopeObj.fft();


            c=0;
            m=0;
            for i= 1 : numel(app.plotChoices)
                if app.plotChoices(i).checkbox.Value
                    if contains(lower(app.plotChoices(i).checkbox.Text),'ch')
                        c=c+1;
                        PlotCH(1,c) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'ch')));
                        plotChTitle(c,1) = string(app.plotChoices(i).TitleEditField.Value);
                    end
                    if contains(lower(app.plotChoices(i).checkbox.Text),'math')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'math')));
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end

                    if contains(lower(app.plotChoices(i).checkbox.Text),'mth')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'mth')));
                        if isnan(PlotMath(1,m))
                            PlotMath(1,m) = m;
                        end
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end
                end

            end
            if app.saveBox.Value
                savePlotEn = 'save';
                savePlotTitle = app.FilenameEditField.Value;
            else
                savePlotEn = [];
                savePlotTitle = [];
            end

            if c>0
                app.scopeObj.advancedFFT('channels',PlotCH,savePlotEn,savePlotTitle,'windowtype', app.WindowDropDown.Value,'titles',plotChTitle,'limit',[app.FFTStarttimeSpinner.Value app.FFTEndtimeSpinner.Value]);
            end

            %             set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        function decode(app,src,event)
            set(app.UIFigure,'Pointer','watch'); drawnow;

            toggleComponents(app,'Off')
            c=0;
            decodeCH = [];
            for i= 1 : numel(app.pnDecodeBox)
                if app.pnDecodeBox(i).Value
                    if contains(lower(app.pnDecodeBox(i).Text),'ch')
                        c=c+1;
                        decodeCH(1,c) = str2double(cell2mat(extractAfter(lower(app.pnDecodeBox(i).Text),'ch')));
                    end
                end

            end
            if ~isempty(decodeCH)
                app.scopeObj = app.scopeObj.decodePN(decodeCH);


                maxPacketsmax = 0;
                for i= 1 : numel(app.pnDecodeBox)
                    if app.pnDecodeBox(i).Value
                        if contains(lower(app.pnDecodeBox(i).Text),'ch')
                            maxPackets  = max([maxPacketsmax numel(app.scopeObj.channels(i).pn)]);
                        end
                    end
                end

                % Create FilenameEditFieldLabel
                app.decodeDoneLabel = uilabel(app.DecodePanel);
                %             app.pnDecodeLabel.HorizontalAlignment = 'right';
                app.decodeDoneLabel.Position = [10 60 62 22];
                app.decodeDoneLabel.Text = 'Done:';
                toggleComponents(app,'On')
                % Create packet range Field
                app.startPlotPacketLabel = uilabel(app.DecodePanel);
                app.startPlotPacketLabel.Position = [20 120 100 22];
                app.startPlotPacketLabel.Text = 'Plot packets from';
                app.startPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.startPlotPacketNr.Position = [120 120 50 22];

                app.endPlotPacketLabel = uilabel(app.DecodePanel);
                app.endPlotPacketLabel.Position = [180 120 20 22];
                app.endPlotPacketLabel.Text = 'to';
                app.endPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.endPlotPacketNr.Position = [200 120 50 22];
                app.endPlotPacketNr.Value= maxPackets;
                % Create plot PN button
                app.PNplotButton = uibutton(app.DecodePanel, 'push');
                app.PNplotButton.Position = [28 90 100 22];
                app.PNplotButton.Text = 'plot';
                app.PNplotButton.ButtonPushedFcn = @app.PNplot;
                assignin('base',"scopeObject",app.scopeObj);

            end
            %% SPE section
            decodeSPE = [];
            c=0;
            for i= 1 : numel(app.speDecodeBox)
                if app.speDecodeBox(i).Value
                    if contains(lower(app.speDecodeBox(i).Text),'ch')
                        c=c+1;
                        decodeSPE(1,c) = str2double(cell2mat(extractAfter(lower(app.speDecodeBox(i).Text),'ch')));
                    end
                end
            end
            if ~isempty(decodeSPE)
                addpath('F:\Documents\1.projects\SPE-decoding-matlab')
                SPEdecoding(app.scopeObj,decodeSPE(1))
            end

            set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        function PNplot(app,src,event)
            figure;
            fontSize= 20;
            set(gca,'fontsize',fontSize+2) % set fontsize of the plot to 20
            set(gcf,'units','normalized','outerposition',[0 0 1 1]) % full screen
            set(0, 'DefaultAxesFontSize', fontSize);
            subplotNr =1
            for i= 1 : numel(app.pnDecodeBox)
                if app.pnDecodeBox(i).Value
                    LastID = find(app.pnDecodeBox(i).Text==' ',1,'last');
                    index = str2double(app.pnDecodeBox(i).Text(LastID+1:end));
                    subplotArray(subplotNr) = subplot(sum([app.pnDecodeBox.Value]),1,subplotNr);
                    app.scopeObj.channels(index).pn.plot(0,1,app.ethHeight,app.ethColor);
                    ylabel(['channel ' num2str(index)]);
                    subplotNr = subplotNr+1;
                end

            end
            sgt=sgtitle('Ethernet packets');
            sgt.FontSize = 20;
            sgt.FontWeight = 'bold';
            linkaxes(subplotArray,'x')
        end

        function pcapPlot(app,src,event)
            if sum([app.pnDecodeBox.Value]) >0
                figure;
                fontSize= 20;
                set(gca,'fontsize',fontSize+2) % set fontsize of the plot to 20
                set(gcf,'units','normalized','outerposition',[0 0 1 1]) % full screen
                set(0, 'DefaultAxesFontSize', fontSize);
                subplotNr=1;
                for i= 1 : numel(app.pnDecodeBox)

                    if app.pnDecodeBox(i).Value
                        LastID = find(app.pnDecodeBox(i).Text==' ',1,'last');
                        index = str2double(app.pnDecodeBox(i).Text(LastID+1:end));
                        subplotArray(subplotNr) = subplot(sum([app.pnDecodeBox.Value]),1,subplotNr);
                        app.scopeObj(index+1).packet.plot(0,1,app.ethHeight,app.ethColor,app.startPlotPacketNr.Value,app.endPlotPacketNr.Value);
                        subplotNr = subplotNr+1;
                        ylabel(['port ' num2str(index)]);
                    end

                end
                sgt = sgtitle('Ethernet packets');
                sgt.FontSize = 20;
                sgt.FontWeight = 'bold';
                linkaxes(subplotArray,'x');
            end
        end


        function plotEnable(app,src,event,x)
            % Create FilenameEditFieldLabel

            if event.Value == 1
                pos = event.Source.Position;
                pos(1) = pos(1)+50;
                pos(3:4) = [62 22];
                app.plotChoices(x).titleLabel = uilabel(app.PlotPanel);
                app.plotChoices(x).titleLabel.HorizontalAlignment = 'right';
                app.plotChoices(x).titleLabel.Position = pos;
                app.plotChoices(x).titleLabel.Text = 'Plot title:';

                % Create FilenameEditField
                app.plotChoices(x).TitleEditField = uieditfield(app.PlotPanel, 'text');
                posEditField = pos(1)+65;

                app.plotChoices(x).TitleEditField.Position = [posEditField pos(2) 150 22];
            else
                if ~isempty(app.plotChoices(x).titleLabel)
                    app.plotChoices(x).titleLabel.delete
                    app.plotChoices(x).TitleEditField.delete;
                end
            end
        end


        function saveOption(app,src,event)

            if event.Value
                % Create FilenameEditFieldLabel
                app.FilenameEditFieldLabel = uilabel(app.PlotPanel);
                app.FilenameEditFieldLabel.HorizontalAlignment = 'right';
                app.FilenameEditFieldLabel.Position = [7 95 62 22];
                app.FilenameEditFieldLabel.Text = 'File name:';

                % Create FilenameEditField
                app.FilenameEditField = uieditfield(app.PlotPanel, 'text');
                app.FilenameEditField.Position = [84 95 235 22];
                app.FilenameEditField.Value = app.scopeObj.fileName;
            else
                app.FilenameEditFieldLabel.delete;
                app.FilenameEditField.delete;
            end
        end

        function toggleComponents(app, enableFlag)
            % enableFlag is either true (Enable=on) or false (enable=off).
            components = [app.GetfolderfileButton,  app.GetmeasurementButton];

            set(components, 'Enable', enableFlag)
        end

        function callAnalyzerApp(app,src,event)
            app.networkAnalyzerApp = networkAnalyzer(app);
        end


        function  informationPanel(app)

            % Create InformationPanel
            app.InformationPanel = uipanel(app.LeftPanel);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Position = [30 58 307 223];

            % Create FilenameLabel
            app.FilenameLabel = uilabel(app.InformationPanel);
            app.FilenameLabel.Position = [16 172 58 22];
            app.FilenameLabel.Text = 'Filename:';

            % Create Filename
            app.Filename = uilabel(app.InformationPanel);
            app.Filename.Position = [79 162 220 45];
            app.Filename.WordWrap = true;


            % Create scopeLampLabel
            app.scopeLampLabel = uilabel(app.InformationPanel);
            app.scopeLampLabel.HorizontalAlignment = 'right';
            app.scopeLampLabel.Position = [13 133 38 22];
            app.scopeLampLabel.Text = 'scope';

            % Create scopeLamp
            app.scopeLamp = uilamp(app.InformationPanel);
            app.scopeLamp.Position = [66 133 20 20];

            % Create pcapLampLabel
            app.pcapLampLabel = uilabel(app.InformationPanel);
            app.pcapLampLabel.HorizontalAlignment = 'right';
            app.pcapLampLabel.Position = [171 132 32 22];
            app.pcapLampLabel.Text = 'pcap';


            % Create pcapLamp
            app.pcapLamp = uilamp(app.InformationPanel);
            app.pcapLamp.Position = [218 132 20 20];


            % Create RecordLengthEditFieldLabel
            app.RecordLengthEditFieldLabel = uilabel(app.InformationPanel);
            app.RecordLengthEditFieldLabel.HorizontalAlignment = 'Left';
            app.RecordLengthEditFieldLabel.Position = [13 96 118 22];
            app.RecordLengthEditFieldLabel.Text = 'Measurement length:';

            % Create RecordLengthEditField
            app.RecordLengthEditField = uieditfield(app.InformationPanel, 'numeric');
            app.RecordLengthEditField.Editable = 'off';
            app.RecordLengthEditField.Position = [142 96 100 22];

            % Create StarttimeEditFieldLabel
            app.StarttimeEditFieldLabel = uilabel(app.InformationPanel);
            app.StarttimeEditFieldLabel.HorizontalAlignment = 'left';
            app.StarttimeEditFieldLabel.Position = [13 66 80 22];
            app.StarttimeEditFieldLabel.Text = 'Start time [s]:';

            % Create StarttimeEditField
            app.StarttimeEditField = uieditfield(app.InformationPanel, 'numeric');
            app.StarttimeEditField.Editable = 'off';
            app.StarttimeEditField.Position = [142 66 100 22];

            % Create EndtimeEditFieldLabel
            app.EndtimeEditFieldLabel = uilabel(app.InformationPanel);
            app.EndtimeEditFieldLabel.HorizontalAlignment = 'left';
            app.EndtimeEditFieldLabel.Position = [13 38 70 22];
            app.EndtimeEditFieldLabel.Text = 'End time [s]:';

            % Create EndtimeEditField
            app.EndtimeEditField = uieditfield(app.InformationPanel, 'numeric');
            app.EndtimeEditField.Editable = 'off';
            app.EndtimeEditField.Position = [142 38 100 22];

            if ~isempty(app.chosenFile)


                if  contains(app.chosenFile{1},'isf') || contains(app.chosenFile{1},'wfm')
                    app.Filename.Text = regexp(lower(app.chosenFile{1}),'(.*)[^(ch|mth|math)[0-9]*.(isf|wfm)]', 'once', 'tokens');
                    app.pcapLamp.Color = [119,136,153]/255 ;
                    app.scopeLamp.Color = [0 1 0];
                    % Create SampleintervalEditFieldLabel
                    app.SampleintervalEditFieldLabel = uilabel(app.InformationPanel);
                    app.SampleintervalEditFieldLabel.HorizontalAlignment = 'left';
                    app.SampleintervalEditFieldLabel.Position = [13 9 118 22];
                    app.SampleintervalEditFieldLabel.Text = 'Sample interval [s]:';

                    % Create SampleintervalEditField
                    app.SampleintervalEditField = uieditfield(app.InformationPanel, 'numeric');
                    app.SampleintervalEditField.Editable = 'off';

                    app.SampleintervalEditField.Position = [142 9 100 22];
                    app.SampleintervalEditField.Value = app.scopeObj.sample_interval;

                    app.RecordLengthEditField.Value = app.scopeObj.sample_length;
                    app.StarttimeEditField.Value = app.scopeObj.time(1);
                    app.EndtimeEditField.Value = app.scopeObj.time(end);


                elseif contains(app.chosenFile,'pcap') || contains(app.chosenFile,'pcapng')
                    app.Filename.Text = app.chosenFile;
                    app.pcapLamp.Color = [0 1 0];
                    app.scopeLamp.Color = [119,136,153]/255;
                    numberOfPackets = 0;
                    startTime = zeros(1,length(app.scopeObj));
                    endTime = zeros(1,length(app.scopeObj));
                    for i = 1:length(app.scopeObj)
                        numberOfPackets = numberOfPackets + length(app.scopeObj(i).packet);
                        if ~isempty(app.scopeObj(i).packet)
                        startTime(i) = app.scopeObj(i).packet(1).time;
                        endTime(i) = app.scopeObj(i).packet(end).time;
                        else
                            startTime(i) = nan;
                            endTime(i) = nan;
                        end
                    end
                    
                    app.RecordLengthEditField.Value = numberOfPackets;
                    app.StarttimeEditField.Value = min(startTime);
                    app.EndtimeEditField.Value = max(endTime);
                else
                    app.pcapLamp.Color = [119,136,153]/255;
                    app.scopeLamp.Color = [119,136,153]/255;
                    app.RecordLengthEditField.Value = 0;
                    app.StarttimeEditField.Value = 0;
                    app.EndtimeEditField.Value = 0;
                end
            else
                app.Filename.Text = "No measurement retrieved";
                app.pcapLamp.Color = [119,136,153]/255;
                app.scopeLamp.Color = [119,136,153]/255;
                app.RecordLengthEditField.Value = 0;
                app.StarttimeEditField.Value = 0;
                app.EndtimeEditField.Value = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
                          app.screenSize = get(groot,'ScreenSize');
              app.screenWidth = app.screenSize(3);
              app.screenHeight = app.screenSize(4);
        end

        % Button pushed function: GetfolderfileButton
        function GetfolderfileButtonPushed(app, event)
            toggleComponents(app,'Off')
            pathUrl = fullfile(userpath, "lastPath.mat");
            if isfile(pathUrl)
                load(pathUrl);
            else
                lastPath = "";
            end
            app.folder = uigetdir(lastPath);


            drawnow;
            figure(app.UIFigure)
            if(app.folder ~= 0)
                lastPath = string(app.folder);
                a=app.Tree.Children;
                a.delete;

                filesAndFolders = dir(app.folder);
                % ...then exclude the folders, to get just a list of files.
                fls = filesAndFolders(~[filesAndFolders.isdir]);
                fls = {fls.name};
                uniqueFiles =[];
                app.files=fls(contains(fls,["wfm","isf",'pcap','pcapng']));
                if sum(contains(app.files,"wfm"))>0
                    lastUnderscore = cellfun(@(x) find(x== '_', 1, 'last'),app.files);
                    uniqueFiles = unique( extractBefore(app.files,lastUnderscore));
                end
                if sum(contains(app.files,"isf"))>0
                    lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),app.files)-3;
                    uniqueFiles = [uniqueFiles unique( extractBefore(app.files,lastUnderscore))];
                end
                if sum(contains(app.files,"pcap"))>0
                    lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),app.files);
                    uniqueFiles = [uniqueFiles unique( extractBefore(app.files,lastUnderscore))];
                end
                %                 if sum(contains(app.files,"pcapng"))>0
                %                     lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),app.files)-6;
                %                     uniqueFiles = [uniqueFiles unique( extractBefore(app.files,lastUnderscore))];
                %                 end

                if ~exist('lastUnderscore','var') || isempty(lastUnderscore)
                    toggleComponents(app,'On')
                    return;
                end

                %                 uniqueFiles = unique( extractBefore(app.files,lastUnderscore));
                if ispc
                    slash = '\';
                else
                    slash = '/';
                end
                % Create Node
                app.Foldertree = uitreenode(app.Tree);
                app.Foldertree.Text =extractAfter(app.folder,find(app.folder== slash, 1, 'last'));
                % Create Node
                for i =1: numel(uniqueFiles)
                    app.Node(i) = uitreenode(app.Foldertree);
                    app.Node(i).Text = uniqueFiles{i};
                end
            else
                app.folder = lastPath;
            end
            toggleComponents(app,'On')

            save(pathUrl, "lastPath");
            clear lastPath fls uniqueFiles slash pathUrl
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.files(contains(app.files,selectedNodes.Text));
            chIndices = contains(lower(app.files),lower(strcat(string(selectedNodes.Text),"_ch")));
            chIndices = chIndices | contains(lower(app.files),lower(strcat(string(selectedNodes.Text),"ch")));
            mathIndices = contains(lower(app.files),lower(strcat(string(selectedNodes.Text),"_math")));
            mathIndices = mathIndices | contains(lower(app.files),lower(strcat(string(selectedNodes.Text),"_math")));
            pcapIndices = contains(lower(app.files),lower(strcat(string(selectedNodes.Text),".pcap")));
            app.selectedFile = app.files(chIndices | mathIndices | pcapIndices);
            % Create CheckBox


        end

        % Button pushed function: GetmeasurementButton
        function GetmeasurementButtonPushed(app, event)

            set(app.UIFigure,'Pointer','watch'); drawnow;
            toggleComponents(app,'Off');
            %             delete(app.measuremetDoneLabel);
            delete(app.decodeDoneLabel);
            delete(app.startPlotPacketLabel);
            delete(app.startPlotPacketNr);
            delete(app.endPlotPacketLabel);
            delete(app.endPlotPacketNr);
            app.chosenFile = [];
            %               app.measuremetDoneLabel = uilabel(app.LeftPanel);
            %                 %             app.measuremetDoneLabel.HorizontalAlignment = 'right';
            %                 app.measuremetDoneLabel.Position = [60 50 150 22];

            %             try
            if isempty(app.selectedFile)
                toggleComponents(app,'On');
                set(app.UIFigure,'Pointer','arrow'); drawnow;
                return;

            elseif ~isempty(app.selectedFile) & contains(app.selectedFile,'wfm')
                app.scopeObj = scope.wfmread(app.selectedFile,0,"app",app.folder);
            elseif ~isempty(app.selectedFile) & (contains(app.selectedFile,'pcap') | contains(app.selectedFile,'pcapng'))
                app.scopeObj = eth.pcapread(fullfile(app.folder,cell2mat(app.selectedFile)),0);
            else
                app.scopeObj = scope.isfread(app.selectedFile,0,"app",app.folder);
            end

            %             app.CheckBox = uicheckbox(app.PlotPanel);
            %

            if contains(app.selectedFile,'wfm') | contains(app.selectedFile,'isf')

                for i =1:numel(app.plotChoices)
                    delete(app.plotChoices(i).checkbox);
                    if isfield(app.plotChoices(i),'titleLabel')
                        delete(app.plotChoices(i).titleLabel);
                        delete(app.plotChoices(i).TitleEditField);
                    end
                end
                app.plotChoices = [];
                delete(app.saveBox);
                delete(app.PlotButton);
                delete(app.FFTButton);
                delete(app.PNplotButton);
                delete(app.pnDecodeBox);
                delete(app.startPlotPacketLabel);
                delete(app.startPlotPacketNr);
                delete(app.endPlotPacketLabel);
                delete(app.endPlotPacketNr);
                delete(app.pnDecodePanel);
                delete(app.speDecodePanel);
                delete(app.speDecodeBox);
                delete(app.DecodeButton);
                delete(app.FFTPanel);
                delete(app.PlotPanel_2);
                delete(app.channelPanel);
                delete(app.mathPanel);

                i=1;
                % Create checkboxes to enable plot for each channel/math of
                % the measurement
                numberOfChannels =length(app.scopeObj.channels);
                
                app.ChannelSelection = uilabel(app.PlotPanel);
                app.ChannelSelection.HorizontalAlignment = 'left';
                app.ChannelSelection.Position = [10 app.screenHeight*0.85 250 22];
                app.ChannelSelection.Text = 'Select channels and maths:';
                app.ChannelSelection.FontWeight = 'bold';
                
                for ii =find(contains(app.files,app.selectedFile),1,'first'): find(contains(app.files,app.selectedFile),1,'last')

                    app.plotChoices(i).checkbox = uicheckbox(app.PlotPanel);
                    app.plotChoices(i).checkbox.Position = [10 app.screenHeight*0.85-(30*i) 80 22];
                    if contains(app.files,"wfm")
                        startExtraction = find(app.files{ii} == '_', 1, 'last');
                    else
                        startExtraction = 7;
                    end
                    endExtraction = find(app.files{ii} == '.', 1, 'last');
                    app.plotChoices(i).checkbox.Text = extractBetween(app.files{ii},startExtraction+1,endExtraction-1);
                    app.plotChoices(i).checkbox.ValueChangedFcn = {@app.plotEnable,i};
                    i = i +1;
                end

                % Create save checkbox
                app.saveBox = uicheckbox(app.PlotPanel);
                app.saveBox.Position = [10 app.screenHeight*0.85-(30*i)-20 80 22];
                app.saveBox.Text = 'Save plots';
                app.saveBox.FontWeight = 'bold';
                app.saveBox.ValueChangedFcn = @app.saveOption;
                % Remove store name field
                app.FilenameEditFieldLabel.delete;
                app.FilenameEditField.delete;

                % ------- Time limit plot function -----
                % Create PlotPanel_2
                app.PlotPanel_2 = uipanel(app.PlotPanel);
                app.PlotPanel_2.Title = 'Plot';
                app.PlotPanel_2.Position = [app.PlotPanel.Position(3)*0.05,58,app.PlotPanel.Position(3)*0.4,185];

                app.channelPlotRangeLabel= uilabel(app.PlotPanel_2);
                app.channelPlotRangeLabel.Position = [12 140 60 22];
                app.channelPlotRangeLabel.Text = 'Channels:';
                app.channelPlotRangeLabel.FontWeight = 'bold'; 
                
                % Create StarttimeLabel
                app.StarttimeLabel = uilabel(app.PlotPanel_2);
                app.StarttimeLabel.HorizontalAlignment = 'right';
                app.StarttimeLabel.Position = [12 110 60 22];
                app.StarttimeLabel.Text = 'Start time:';

                % Create StarttimeSpinner
                app.StarttimeSpinner = uispinner(app.PlotPanel_2);
                app.StarttimeSpinner.Position = [73 110 200 22];
                app.StarttimeSpinner.Value = app.scopeObj.time(1);

                % Create EndtimeLabel
                app.EndtimeLabel = uilabel(app.PlotPanel_2);
                app.EndtimeLabel.HorizontalAlignment = 'right';
                app.EndtimeLabel.Position = [12 80 56 22];
                app.EndtimeLabel.Text = 'End time:';

                % Create EndtimeSpinner
                app.EndtimeSpinner = uispinner(app.PlotPanel_2);
                app.EndtimeSpinner.Position = [73 80 200 22];
                app.EndtimeSpinner.Value = app.scopeObj.time(end);


                % Create PlotButton
                app.PlotButton = uibutton(app.PlotPanel_2, 'push');
                app.PlotButton.Position = [190,10,100,22];
                app.PlotButton.Text = 'Plot';
                %                 app.PlotButton.BusyAction = 'cancel';
                %                 app.PlotButton.Interruptible = 'off';
                app.PlotButton.ButtonPushedFcn = @app.plotting;


                % ------- Advanced FFT function -----
                % Create PlotPanel_2
                app.FFTPanel= uipanel(app.PlotPanel);
                app.FFTPanel.Title = 'FFT';
                app.FFTPanel.Position = [app.PlotPanel.Position(3)*0.55,58,app.PlotPanel.Position(3)*0.4,185];

                % Create StarttimeLabel
                app.FFTStarttimeLabel = uilabel(app.FFTPanel);
                app.FFTStarttimeLabel.HorizontalAlignment = 'right';
                app.FFTStarttimeLabel.Position = [12 130 60 22];
                app.FFTStarttimeLabel.Text = 'Start time:';

                % Create StarttimeSpinner
                app.FFTStarttimeSpinner = uispinner(app.FFTPanel);
                app.FFTStarttimeSpinner.Position = [73 130 200 22];
                app.FFTStarttimeSpinner.Value = app.scopeObj.time(1);

                % Create EndtimeLabel
                app.FFTEndtimeLabel = uilabel(app.FFTPanel);
                app.FFTEndtimeLabel.HorizontalAlignment = 'right';
                app.FFTEndtimeLabel.Position = [12 90 56 22];
                app.FFTEndtimeLabel.Text = 'End time:';

                % Create EndtimeSpinner
                app.FFTEndtimeSpinner = uispinner(app.FFTPanel);
                app.FFTEndtimeSpinner.Position = [73 90 200 22];
                app.FFTEndtimeSpinner.Value = app.scopeObj.time(end);

                % Create WindowDropDownLabel
                app.WindowDropDownLabel = uilabel(app.FFTPanel);
                app.WindowDropDownLabel.HorizontalAlignment = 'right';
                app.WindowDropDownLabel.Position = [12 50 55 22];
                app.WindowDropDownLabel.Text = 'Window: ';

                % Create WindowDropDown
                app.WindowDropDown = uidropdown(app.FFTPanel);
                app.WindowDropDown.Items = {'hanning', 'hamming', 'rectangle', 'blackmanharris', 'gaussian'};
                app.WindowDropDown.Position = [73 50 100 22];
                app.WindowDropDown.Value = 'hanning';

                % Create FFTButton
                app.FFTButton = uibutton(app.FFTPanel, 'push');
                app.FFTButton.Position = [190 10 100 22];
                app.FFTButton.Text = 'FFT';
                %                 app.FFTButton.BusyAction = 'cancel';
                %                 app.FFTButton.Interruptible = 'off';
                app.FFTButton.ButtonPushedFcn = @app.plotFFT;

                % Create DecodePanel
                app.DecodePanel = uipanel(app.GridLayout);
                app.DecodePanel.Title = 'Ethernet section';
                app.DecodePanel.Layout.Row = 1;
                app.DecodePanel.Layout.Column = 3;
                app.DecodePanel.FontWeight = 'bold';
                app.DecodePanel.FontSize = 16;
                i=1;
                fileID = contains(app.files,app.selectedFile);
                
                app.pnDecodePanel= uipanel(app.DecodePanel);
                app.pnDecodePanel.Position = [10 (app.screenHeight*0.85-30*numberOfChannels) 120 30*numberOfChannels+20];
                app.pnDecodePanel.Title = 'PROFINET';
                
                app.speDecodePanel= uipanel(app.DecodePanel);
                app.speDecodePanel.Position = [140 (app.screenHeight*0.85-30*numberOfChannels) 120 30*numberOfChannels+20];
                app.speDecodePanel.Title = 'Single Pair Ethernet';


                for ii =find(contains(lower(app.files(fileID)),'ch' ),1,'first'): find(contains(lower(app.files(fileID)),'ch' ),1,'last')

                    app.pnDecodeBox(i) = uicheckbox(app.pnDecodePanel);
                    app.pnDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];
                    if contains(app.files,"wfm")
                        startExtraction = find(app.files{ii} == '_', 1, 'last');
                    else
                        startExtraction = 7;
                    end
                    endExtraction = find(app.files{ii} == '.', 1, 'last');
                    app.pnDecodeBox(i).Text = extractBetween(app.files{ii},startExtraction+1,endExtraction-1);


                    % SPE section
                    app.speDecodeBox(i) = uicheckbox(app.speDecodePanel);
                    app.speDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];
                    if contains(app.files,"wfm")
                        startExtraction = find(app.files{ii} == '_', 1, 'last');
                    else
                        startExtraction = 7;
                    end
                    endExtraction = find(app.files{ii} == '.', 1, 'last');
                    app.speDecodeBox(i).Text = extractBetween(app.files{ii},startExtraction+1,endExtraction-1);
                    i = i +1;

                end


                % Create DecodeButton
                app.DecodeButton = uibutton(app.DecodePanel, 'push');
                app.DecodeButton.Position = [28 37 100 22];
                app.DecodeButton.Text = 'Decode';
                %                 app.DecodeButton.BusyAction = 'cancel';
                %                 app.DecodeButton.Interruptible = 'off';
                app.DecodeButton.ButtonPushedFcn = @app.decode;


                app.chosenFile = app.selectedFile;
                assignin('base',"scopeObject",app.scopeObj);


            end

            if contains(app.selectedFile,'pcap') | contains(app.selectedFile,'pcapng')

                for i =1:numel(app.plotChoices)
                    delete(app.plotChoices(i).checkbox);
                    delete(app.plotChoices(i).titleLabel);
                    delete(app.plotChoices(i).TitleEditField);
                end
                delete(app.saveBox);
                delete(app.PlotButton);
                delete(app.PNplotButton);
                delete(app.pnDecodeBox);
                delete(app.FFTButton);
                delete(app.startPlotPacketLabel);
                delete(app.startPlotPacketNr);
                delete(app.endPlotPacketLabel);
                delete(app.endPlotPacketNr);
                delete(app.FFTPanel);
                delete(app.PlotPanel_2);


                % Create DecodePanel
                app.DecodePanel = uipanel(app.GridLayout);
                app.DecodePanel.Title = 'Ethernet section';
                app.DecodePanel.Layout.Row = 1;
                app.DecodePanel.Layout.Column = 3;
                app.DecodePanel.FontWeight = 'bold';
                app.DecodePanel.FontSize = 16;
                maxPackets = 0;
                startID = find(cellfun(@isempty,{app.scopeObj.packet})==0 ,1,'first');
                for ii =startID: numel(app.scopeObj)
                    app.pnDecodeBox(ii-(startID-1)) = uicheckbox(app.DecodePanel);
                    app.pnDecodeBox(ii-(startID-1)).Position = [12 368-(30*(ii-(startID-1))) 150 22];
                    app.pnDecodeBox(ii-(startID-1)).Text = [app.scopeObj(ii).packet(1).frame.encapsulation_desc ' port ' num2str(ii-1)];
                    maxPackets  = max([maxPackets max(numel(app.scopeObj(ii).packet))]);

                end

                % Create packet range Field
                app.startPlotPacketLabel = uilabel(app.DecodePanel);
                app.startPlotPacketLabel.Position = [20 120 100 22];
                app.startPlotPacketLabel.Text = 'Plot packets from';
                app.startPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.startPlotPacketNr.Position = [120 120 50 22];

                app.endPlotPacketLabel = uilabel(app.DecodePanel);
                app.endPlotPacketLabel.Position = [180 120 20 22];
                app.endPlotPacketLabel.Text = 'to';
                app.endPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.endPlotPacketNr.Position = [200 120 50 22];
                app.endPlotPacketNr.Value= maxPackets;


                % Create plot PN button

                app.PNplotButton = uibutton(app.DecodePanel, 'push');
                app.PNplotButton.Position = [28 90 100 22];
                app.PNplotButton.Text = 'plot';
                %                 app.PNplotButton.BusyAction = 'cancel';
                %                 app.PNplotButton.Interruptible = 'off';
                app.PNplotButton.ButtonPushedFcn = @app.pcapPlot;

                app.ethAnalyzeButton = uibutton(app.DecodePanel, 'push');
                app.ethAnalyzeButton.Position = [28 60 100 22];
                app.ethAnalyzeButton.Text = 'Analyze app';
                app.ethAnalyzeButton.ButtonPushedFcn = @app.callAnalyzerApp;

                app.chosenFile = app.selectedFile;
                assignin('base',"pcapPackets",app.scopeObj);
            end


            %                 app.measuremetDoneLabel.Text = 'Done';

            informationPanel(app);

            toggleComponents(app,'On');
            set(app.UIFigure,'Pointer','arrow'); drawnow;
            %             catch
            %                 informationPanel(app);
            %                 for i =1:numel(app.plotChoices)
            %                     delete(app.plotChoices(i).checkbox);
            %                 end
            %                 delete(app.saveBox);
            %                 delete(app.PlotButton);
            %                 delete(app.FFTButton);
            %                 delete(app.PNplotButton);
            %                 delete(app.pnDecodeBox);
            %                 delete(app.startPlotPacketLabel);
            %                 delete(app.startPlotPacketNr);
            %                 delete(app.endPlotPacketLabel);
            %                 delete(app.endPlotPacketNr);
            %                 delete(app.pnDecodeLabel);
            %                 delete(app.speDecodeLabel);
            %                 delete(app.speDecodeBox);
            %                 delete(app.DecodeButton);
            %                 toggleComponents(app,'On');
            %                 set(app.UIFigure,'Pointer','arrow'); drawnow;
            %             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.networkAnalyzerApp)
            delete(app)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Get measurement';
            app.GetfolderfileButton = uibutton(app.LeftPanel, 'push');
            app.GetfolderfileButton.ButtonPushedFcn = createCallbackFcn(app, @GetfolderfileButtonPushed, true);
            app.GetfolderfileButton.Text = 'Get folder/file';
            app.Tree = uitree(app.LeftPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GetmeasurementButton = uibutton(app.LeftPanel, 'push');
            app.GetmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @GetmeasurementButtonPushed, true);
            app.GetmeasurementButton.Text = 'Get measurement';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'KULEUVEN_CMYK_LOGO.png';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'CINI4.0_1920.png';
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot section';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scopeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end